classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        NLocalTab                       matlab.ui.container.Tab
        n_timeEditField                 matlab.ui.control.NumericEditField
        n_timeEditFieldLabel            matlab.ui.control.Label
        n_rightEditField                matlab.ui.control.NumericEditField
        n_rightEditFieldLabel           matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        S0EditField                     matlab.ui.control.NumericEditField
        S0EditFieldLabel                matlab.ui.control.Label
        l_maxEditField                  matlab.ui.control.NumericEditField
        l_maxEditFieldLabel             matlab.ui.control.Label
        l_minEditField                  matlab.ui.control.NumericEditField
        l_minEditFieldLabel             matlab.ui.control.Label
        risk_premiumEditField           matlab.ui.control.NumericEditField
        risk_premiumEditFieldLabel      matlab.ui.control.Label
        kappaEditField                  matlab.ui.control.NumericEditField
        kappaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        muEditField                     matlab.ui.control.NumericEditField
        muEditFieldLabel                matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        rEditFieldLabel                 matlab.ui.control.Label
        N_startEditField                matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        N_stepEditField                 matlab.ui.control.NumericEditField
        N_stopEditField                 matlab.ui.control.NumericEditField
        StatusLabel                     matlab.ui.control.Label
        CompileButton                   matlab.ui.control.StateButton
        NGlobalTab                      matlab.ui.container.Tab
        g_maxEditField_N_Global         matlab.ui.control.NumericEditField
        g_maxEditFieldLabel             matlab.ui.control.Label
        g_minEditField_N_Global         matlab.ui.control.NumericEditField
        g_minEditFieldLabel             matlab.ui.control.Label
        mEditField_N_Global             matlab.ui.control.NumericEditField
        mEditFieldLabel                 matlab.ui.control.Label
        N_startEditField_N_Global       matlab.ui.control.NumericEditField
        NEditFieldLabel_3               matlab.ui.control.Label
        TEditField_N_Global             matlab.ui.control.NumericEditField
        TEditField_2Label_2             matlab.ui.control.Label
        rEditField_N_Global             matlab.ui.control.NumericEditField
        rEditField_2Label_2             matlab.ui.control.Label
        risk_premiumEditField_N_Global  matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_2  matlab.ui.control.Label
        kappaEditField_N_Global         matlab.ui.control.NumericEditField
        kappaEditField_2Label_2         matlab.ui.control.Label
        sigmaEditField_N_Global         matlab.ui.control.NumericEditField
        sigmaEditField_2Label_2         matlab.ui.control.Label
        muEditField_N_Global            matlab.ui.control.NumericEditField
        muEditField_2Label_2            matlab.ui.control.Label
        KEditField_N_Global             matlab.ui.control.NumericEditField
        KEditField_2Label_2             matlab.ui.control.Label
        S0EditField_N_Global            matlab.ui.control.NumericEditField
        S0EditField_2Label_2            matlab.ui.control.Label
        l_maxEditField_N_Global         matlab.ui.control.NumericEditField
        l_maxEditField_2Label_2         matlab.ui.control.Label
        l_minEditField_N_Global         matlab.ui.control.NumericEditField
        l_minEditField_3Label           matlab.ui.control.Label
        n_timeEditField_N_Global        matlab.ui.control.NumericEditField
        n_timeEditField_2Label_2        matlab.ui.control.Label
        n_rightEditField_N_Global       matlab.ui.control.NumericEditField
        n_rightEditField_2Label         matlab.ui.control.Label
        N_stepEditField_N_Global        matlab.ui.control.NumericEditField
        N_stopEditField_N_Global        matlab.ui.control.NumericEditField
        StatusLabel_N_Global            matlab.ui.control.Label
        CompileButton_N_Global          matlab.ui.control.StateButton
        n_rightLocalTab                 matlab.ui.container.Tab
        risk_premiumEditField_Local_n_right  matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_3  matlab.ui.control.Label
        NEditField_Local_n_right        matlab.ui.control.NumericEditField
        NEditFieldLabel_4               matlab.ui.control.Label
        TEditField_Local_n_right        matlab.ui.control.NumericEditField
        TEditField_2Label_3             matlab.ui.control.Label
        rEditField_Local_n_right        matlab.ui.control.NumericEditField
        rEditField_2Label_3             matlab.ui.control.Label
        kappaEditField_Local_n_right    matlab.ui.control.NumericEditField
        kappaEditField_2Label_3         matlab.ui.control.Label
        sigmaEditField_Local_n_right    matlab.ui.control.NumericEditField
        sigmaEditField_2Label_3         matlab.ui.control.Label
        muEditField_Local_n_right       matlab.ui.control.NumericEditField
        muEditField_2Label_3            matlab.ui.control.Label
        KEditField_Local_n_right        matlab.ui.control.NumericEditField
        KEditField_2Label_3             matlab.ui.control.Label
        S0EditField_Local_n_right       matlab.ui.control.NumericEditField
        S0EditField_2Label_3            matlab.ui.control.Label
        l_maxEditField_Local_n_right    matlab.ui.control.NumericEditField
        l_maxEditField_2Label_3         matlab.ui.control.Label
        l_minEditField_Local_n_right    matlab.ui.control.NumericEditField
        l_minEditField_2Label_3         matlab.ui.control.Label
        n_timeEditField_Local_n_right   matlab.ui.control.NumericEditField
        n_timeEditField_2Label_3        matlab.ui.control.Label
        StatusLabel_Local_n_right       matlab.ui.control.Label
        CompileButton_Local_n_right     matlab.ui.control.StateButton
        n_rightGlobalTab                matlab.ui.container.Tab
        g_stopEditField_Global_n_right  matlab.ui.control.NumericEditField
        g_stepEditField_Global_n_right  matlab.ui.control.NumericEditField
        g_startEditField_Global_n_right  matlab.ui.control.NumericEditField
        gLabel                          matlab.ui.control.Label
        risk_premiumEditField_Global_n_right  matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label    matlab.ui.control.Label
        mEditField_Global_n_right       matlab.ui.control.NumericEditField
        l_minEditField_2Label_2         matlab.ui.control.Label
        NEditField_Global_n_right       matlab.ui.control.NumericEditField
        NEditFieldLabel_2               matlab.ui.control.Label
        TEditField_Global_n_right       matlab.ui.control.NumericEditField
        TEditField_2Label               matlab.ui.control.Label
        rEditField_Global_n_right       matlab.ui.control.NumericEditField
        rEditField_2Label               matlab.ui.control.Label
        kappaEditField_Global_n_right   matlab.ui.control.NumericEditField
        kappaEditField_2Label           matlab.ui.control.Label
        sigmaEditField_Global_n_right   matlab.ui.control.NumericEditField
        sigmaEditField_2Label           matlab.ui.control.Label
        muEditField_Global_n_right      matlab.ui.control.NumericEditField
        muEditField_2Label              matlab.ui.control.Label
        KEditField__Global_n_right      matlab.ui.control.NumericEditField
        KEditField_2Label               matlab.ui.control.Label
        S0EditField_Global_n_right      matlab.ui.control.NumericEditField
        S0EditField_2Label              matlab.ui.control.Label
        l_maxEditField_Global_n_right   matlab.ui.control.NumericEditField
        l_maxEditField_2Label           matlab.ui.control.Label
        l_minEditField_Global_n_right   matlab.ui.control.NumericEditField
        l_minEditField_2Label           matlab.ui.control.Label
        n_timeEditField_Global_n_right  matlab.ui.control.NumericEditField
        n_timeEditField_2Label          matlab.ui.control.Label
        StatusLabel_Global_n_right      matlab.ui.control.Label
        CompileButton_Global_n_right    matlab.ui.control.StateButton
        StricePriceandlocalconstraintTab  matlab.ui.container.Tab
        KEditField_K_l_stop             matlab.ui.control.NumericEditField
        KEditField_K_l_step             matlab.ui.control.NumericEditField
        l_minEditField_K_l_3            matlab.ui.control.NumericEditField
        l_minEditField_K_l_2            matlab.ui.control.NumericEditField
        l_maxEditField_K_l_3            matlab.ui.control.NumericEditField
        l_maxEditField_K_l_2            matlab.ui.control.NumericEditField
        n_rightEditField_K_l            matlab.ui.control.NumericEditField
        n_timeEditField_2Label_5        matlab.ui.control.Label
        risk_premiumEditField_K_l       matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_4  matlab.ui.control.Label
        NEditField_K_l                  matlab.ui.control.NumericEditField
        NEditFieldLabel_5               matlab.ui.control.Label
        TEditField_K_l                  matlab.ui.control.NumericEditField
        TEditField_2Label_4             matlab.ui.control.Label
        rEditField_K_l                  matlab.ui.control.NumericEditField
        rEditField_2Label_4             matlab.ui.control.Label
        kappaEditField_K_l              matlab.ui.control.NumericEditField
        kappaEditField_2Label_4         matlab.ui.control.Label
        sigmaEditField_K_l              matlab.ui.control.NumericEditField
        sigmaEditField_2Label_4         matlab.ui.control.Label
        muEditField_K_l                 matlab.ui.control.NumericEditField
        muEditField_2Label_4            matlab.ui.control.Label
        KEditField_K_l_start            matlab.ui.control.NumericEditField
        KEditField_2Label_4             matlab.ui.control.Label
        S0EditField_K_l                 matlab.ui.control.NumericEditField
        S0EditField_2Label_4            matlab.ui.control.Label
        l_maxEditField_K_l_1            matlab.ui.control.NumericEditField
        l_maxEditField_2Label_4         matlab.ui.control.Label
        l_minEditField_K_l_1            matlab.ui.control.NumericEditField
        l_minEditField_2Label_4         matlab.ui.control.Label
        n_timeEditField_K_l             matlab.ui.control.NumericEditField
        n_timeEditField_2Label_4        matlab.ui.control.Label
        StatusLabel_K_l                 matlab.ui.control.Label
        CompileButton_K_l               matlab.ui.control.StateButton
        StrikePriceandLongrunmeanTab    matlab.ui.container.Tab
        StatusLabel_K_mu                matlab.ui.control.Label
        muEditField_K_mu_3              matlab.ui.control.NumericEditField
        muEditField_K_mu_2              matlab.ui.control.NumericEditField
        KEditField_K_mu_stop            matlab.ui.control.NumericEditField
        n_rightEditField_K_mu           matlab.ui.control.NumericEditField
        n_timeEditField_2Label_7        matlab.ui.control.Label
        risk_premiumEditField_K_mu      matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_5  matlab.ui.control.Label
        NEditField_K_mu                 matlab.ui.control.NumericEditField
        NEditFieldLabel_6               matlab.ui.control.Label
        TEditField_K_mu                 matlab.ui.control.NumericEditField
        TEditField_2Label_5             matlab.ui.control.Label
        rEditField_K_mu                 matlab.ui.control.NumericEditField
        rEditField_2Label_5             matlab.ui.control.Label
        kappaEditField_K_mu             matlab.ui.control.NumericEditField
        kappaEditField_2Label_5         matlab.ui.control.Label
        sigmaEditField_K_mu             matlab.ui.control.NumericEditField
        sigmaEditField_2Label_5         matlab.ui.control.Label
        muEditField_K_mu_1              matlab.ui.control.NumericEditField
        muEditField_2Label_5            matlab.ui.control.Label
        KEditField_K_mu_start           matlab.ui.control.NumericEditField
        KEditField_2Label_5             matlab.ui.control.Label
        S0EditField_K_mu                matlab.ui.control.NumericEditField
        S0EditField_2Label_5            matlab.ui.control.Label
        l_maxEditField_K_mu             matlab.ui.control.NumericEditField
        l_maxEditField_2Label_5         matlab.ui.control.Label
        l_minEditField_K_mu             matlab.ui.control.NumericEditField
        l_minEditField_2Label_5         matlab.ui.control.Label
        n_timeEditField_K_mu            matlab.ui.control.NumericEditField
        n_timeEditField_2Label_6        matlab.ui.control.Label
        KEditField_K_mu_step            matlab.ui.control.NumericEditField
        CompileButton_K_mu              matlab.ui.control.StateButton
        KappaandLongrunmeanTab          matlab.ui.container.Tab
        kappaEditField_mu_kappa_stop    matlab.ui.control.NumericEditField
        kappaEditField_mu_kappa_step    matlab.ui.control.NumericEditField
        n_rightEditField_mu_kappa       matlab.ui.control.NumericEditField
        n_timeEditField_2Label_9        matlab.ui.control.Label
        risk_premiumEditField_mu_kappa  matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_6  matlab.ui.control.Label
        NEditField_mu_kappa             matlab.ui.control.NumericEditField
        NEditFieldLabel_7               matlab.ui.control.Label
        TEditField_mu_kappa             matlab.ui.control.NumericEditField
        TEditField_2Label_6             matlab.ui.control.Label
        rEditField_mu_kappa             matlab.ui.control.NumericEditField
        rEditField_2Label_6             matlab.ui.control.Label
        kappaEditField_mu_kappa_start   matlab.ui.control.NumericEditField
        kappaEditField_2Label_6         matlab.ui.control.Label
        sigmaEditField_mu_kappa         matlab.ui.control.NumericEditField
        sigmaEditField_2Label_6         matlab.ui.control.Label
        muEditField_mu_kappa_start      matlab.ui.control.NumericEditField
        muEditField_2Label_6            matlab.ui.control.Label
        KEditField_mu_kappa             matlab.ui.control.NumericEditField
        KEditField_2Label_6             matlab.ui.control.Label
        S0EditField_mu_kappa            matlab.ui.control.NumericEditField
        S0EditField_2Label_6            matlab.ui.control.Label
        l_maxEditField_mu_kappa         matlab.ui.control.NumericEditField
        l_maxEditField_2Label_6         matlab.ui.control.Label
        l_minEditField_mu_kappa         matlab.ui.control.NumericEditField
        l_minEditField_2Label_6         matlab.ui.control.Label
        n_timeEditField_mu_kappa        matlab.ui.control.NumericEditField
        n_timeEditField_2Label_8        matlab.ui.control.Label
        StatusLabel_mu_kappa            matlab.ui.control.Label
        muEditField_mu_kappa_stop       matlab.ui.control.NumericEditField
        muEditField_mu_kappa_step       matlab.ui.control.NumericEditField
        CompileButton_mu_kappa          matlab.ui.control.StateButton
        S0andlongrunmeanTab             matlab.ui.container.Tab
        StatusLabel_S0_kappa            matlab.ui.control.Label
        S0EditField_S0_kappa_stop       matlab.ui.control.NumericEditField
        S0EditField_S0_kappa_step       matlab.ui.control.NumericEditField
        n_rightEditField_S0_kappa       matlab.ui.control.NumericEditField
        n_timeEditField_2Label_11       matlab.ui.control.Label
        risk_premiumEditField_S0_kappa  matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_7  matlab.ui.control.Label
        NEditField_S0_kappa             matlab.ui.control.NumericEditField
        NEditFieldLabel_8               matlab.ui.control.Label
        TEditField_S0_kappa             matlab.ui.control.NumericEditField
        TEditField_2Label_7             matlab.ui.control.Label
        rEditField_S0_kappa             matlab.ui.control.NumericEditField
        rEditField_2Label_7             matlab.ui.control.Label
        kappaEditField_S0_kappa_start   matlab.ui.control.NumericEditField
        kappaEditField_2Label_7         matlab.ui.control.Label
        sigmaEditField_S0_kappa         matlab.ui.control.NumericEditField
        sigmaEditField_2Label_7         matlab.ui.control.Label
        muEditField_S0_kappa            matlab.ui.control.NumericEditField
        muEditField_2Label_7            matlab.ui.control.Label
        KEditField_S0_kappa             matlab.ui.control.NumericEditField
        KEditField_2Label_7             matlab.ui.control.Label
        S0EditField_S0_kappa_start      matlab.ui.control.NumericEditField
        S0EditField_2Label_7            matlab.ui.control.Label
        l_maxEditField_S0_kappa         matlab.ui.control.NumericEditField
        l_maxEditField_2Label_7         matlab.ui.control.Label
        l_minEditField_S0_kappa         matlab.ui.control.NumericEditField
        l_minEditField_2Label_7         matlab.ui.control.Label
        n_timeEditField_S0_kappa        matlab.ui.control.NumericEditField
        n_timeEditField_2Label_10       matlab.ui.control.Label
        kappaEditField_S0_kappa_stop    matlab.ui.control.NumericEditField
        kappaEditField_S0_kappa_step    matlab.ui.control.NumericEditField
        CompileButton_S0_kappa          matlab.ui.control.StateButton
        sigmaTab                        matlab.ui.container.Tab
        sigmaEditField_sigma_stop       matlab.ui.control.NumericEditField
        sigmaEditField_sigma_step       matlab.ui.control.NumericEditField
        n_rightEditField_sigma          matlab.ui.control.NumericEditField
        n_timeEditField_2Label_13       matlab.ui.control.Label
        risk_premiumEditField_sigma     matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_8  matlab.ui.control.Label
        NEditField_sigma                matlab.ui.control.NumericEditField
        NEditFieldLabel_9               matlab.ui.control.Label
        TEditField_sigma                matlab.ui.control.NumericEditField
        TEditField_2Label_8             matlab.ui.control.Label
        rEditField_sigma                matlab.ui.control.NumericEditField
        rEditField_2Label_8             matlab.ui.control.Label
        kappaEditField_sigma            matlab.ui.control.NumericEditField
        kappaEditField_2Label_8         matlab.ui.control.Label
        sigmaEditField_sigma_start      matlab.ui.control.NumericEditField
        sigmaEditField_2Label_8         matlab.ui.control.Label
        muEditField_sigma               matlab.ui.control.NumericEditField
        muEditField_2Label_8            matlab.ui.control.Label
        KEditField_sigma                matlab.ui.control.NumericEditField
        KEditField_2Label_8             matlab.ui.control.Label
        S0EditField_sigma               matlab.ui.control.NumericEditField
        S0EditField_2Label_8            matlab.ui.control.Label
        l_maxEditField_sigma            matlab.ui.control.NumericEditField
        l_maxEditField_2Label_8         matlab.ui.control.Label
        l_minEditField_sigma            matlab.ui.control.NumericEditField
        l_minEditField_2Label_8         matlab.ui.control.Label
        n_timeEditField_sigma           matlab.ui.control.NumericEditField
        n_timeEditField_2Label_12       matlab.ui.control.Label
        StatusLabel_sigma               matlab.ui.control.Label
        CompileButton_sigma             matlab.ui.control.StateButton
        riskfreeTab                     matlab.ui.container.Tab
        rEditField_r_stop               matlab.ui.control.NumericEditField
        rEditField_r_step               matlab.ui.control.NumericEditField
        expected_returnEditField_r_stop  matlab.ui.control.NumericEditField
        expected_returnEditField_r_step  matlab.ui.control.NumericEditField
        n_rightEditField_r              matlab.ui.control.NumericEditField
        n_timeEditField_2Label_15       matlab.ui.control.Label
        expected_returnEditField_r_start  matlab.ui.control.NumericEditField
        risk_premiumEditField_2Label_9  matlab.ui.control.Label
        NEditField_r                    matlab.ui.control.NumericEditField
        NEditFieldLabel_10              matlab.ui.control.Label
        TEditField_r                    matlab.ui.control.NumericEditField
        TEditField_2Label_9             matlab.ui.control.Label
        rEditField_r_start              matlab.ui.control.NumericEditField
        rEditField_2Label_9             matlab.ui.control.Label
        kappaEditField_r                matlab.ui.control.NumericEditField
        kappaEditField_2Label_9         matlab.ui.control.Label
        sigmaEditField_r                matlab.ui.control.NumericEditField
        sigmaEditField_2Label_9         matlab.ui.control.Label
        muEditField_r                   matlab.ui.control.NumericEditField
        muEditField_2Label_9            matlab.ui.control.Label
        KEditField_r                    matlab.ui.control.NumericEditField
        KEditField_2Label_9             matlab.ui.control.Label
        S0EditField_r                   matlab.ui.control.NumericEditField
        S0EditField_2Label_9            matlab.ui.control.Label
        l_maxEditField_r                matlab.ui.control.NumericEditField
        l_maxEditField_2Label_9         matlab.ui.control.Label
        l_minEditField_r                matlab.ui.control.NumericEditField
        l_minEditField_2Label_9         matlab.ui.control.Label
        n_timeEditField_r               matlab.ui.control.NumericEditField
        n_timeEditField_2Label_14       matlab.ui.control.Label
        StatusLabel_r                   matlab.ui.control.Label
        CompileButton_r                 matlab.ui.control.StateButton
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CompileButton
        function CompileButtonValueChanged(app, event)
            app.StatusLabel.Text = "Running";
            app.CompileButton.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');

            n_right = app.n_rightEditField.Value;
            n_time = app.n_timeEditField.Value;
            l_min = app.l_minEditField.Value;
            l_max = app.l_maxEditField.Value;
            S0 = app.S0EditField.Value;
            K = app.KEditField.Value;
            mu = app.muEditField.Value;
            sigma = app.sigmaEditField.Value;
            kappa = app.kappaEditField.Value;
            risk_premium = app.risk_premiumEditField.Value;
            r = app.rEditField.Value;
            T = app.TEditField.Value;
            N_start = app.N_startEditField.Value;
            N_stop = app.N_stopEditField.Value;
            N_step = app.N_stepEditField.Value;
            PlotSwingLocalNR_N(n_right, n_time, l_min, l_max, S0, K, mu, ...
                            sigma, kappa, risk_premium, r, T, (N_start:N_step:N_stop), app.UIAxes);

            app.StatusLabel.Text = "Finish";
            app.CompileButton.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_Global_n_right
        function CompileButton_Global_n_rightValueChanged(app, event)
            app.StatusLabel_Global_n_right.Text = "Running";
            app.CompileButton_Global_n_right.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');

            n_time = app.n_timeEditField_Global_n_right.Value;
            n_right = (1: 1: n_time);
            m = app.mEditField_Global_n_right.Value;
            l_min = app.l_minEditField_Global_n_right.Value;
            l_max = app.l_maxEditField_Global_n_right.Value;
            S0 = app.S0EditField_Global_n_right.Value;
            K = app.mEditField_Global_n_right.Value;
            mu = app.muEditField_Global_n_right.Value;
            sigma = app.sigmaEditField_Global_n_right.Value;
            kappa = app.kappaEditField_Global_n_right.Value;
            risk_premium = app.risk_premiumEditField_Global_n_right.Value;
            r = app.rEditField_Global_n_right.Value;
            T = app.TEditField_Global_n_right.Value;
            N = app.NEditField_Global_n_right.Value;
            g_start = app.g_startEditField_Global_n_right.Value;
            g_step = app.g_stepEditField_Global_n_right.Value;
            g_stop = app.g_stopEditField_Global_n_right.Value;
            PlotSwingLocalGlobalNR_g_nright_3D(n_right, n_time, m,...
    l_min, l_max, S0, K, mu, sigma, kappa, risk_premium, r, T, N, (g_start:g_step:g_stop), app.UIAxes)

            app.CompileButton_Global_n_right.Enable = 'on';
            app.StatusLabel_Global_n_right.Text = "Finish";
            drawnow;
        end

        % Value changed function: CompileButton_N_Global
        function CompileButton_N_GlobalValueChanged(app, event)
            app.StatusLabel_N_Global.Text = "Running";
            app.CompileButton_N_Global.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_N_Global.Value;
            n_time = app.n_timeEditField_N_Global.Value;
            m = app.mEditField_N_Global.Value;
            l_min = app.l_minEditField_N_Global.Value;
            l_max = app.l_maxEditField_N_Global.Value;
            g_min = app.g_minEditField_N_Global.Value;
            g_max = app.g_maxEditField_N_Global.Value;
            S0 = app.S0EditField_N_Global.Value;
            K = app.KEditField_N_Global.Value;
            mu = app.muEditField_N_Global.Value;
            sigma = app.sigmaEditField_N_Global.Value;
            kappa = app.kappaEditField_N_Global.Value;
            risk_premium = app.risk_premiumEditField_N_Global.Value;
            r = app.rEditField_N_Global.Value;
            T = app.TEditField_N_Global.Value;
            N_start = app.N_startEditField_N_Global.Value;
            N_stop = app.N_stopEditField_N_Global.Value;
            N_step = app.N_stepEditField_N_Global.Value;
            PlotSwingGlobalNR_N(n_right, n_time, m, l_min, l_max, ...
                      g_min, g_max, S0, K, mu, sigma, kappa, risk_premium, ... 
                      r, T, (N_start:N_step:N_stop), app.UIAxes)

            app.StatusLabel_N_Global.Text = "Finish";
            app.CompileButton_N_Global.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_Local_n_right
        function CompileButton_Local_n_rightValueChanged(app, event)
            app.StatusLabel_Local_n_right.Text = "Running";
            app.CompileButton_Local_n_right.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');

            n_time = app.n_timeEditField_Local_n_right.Value;
            l_min = app.l_minEditField_Local_n_right.Value;
            l_max = app.l_maxEditField_Local_n_right.Value;
            S0 = app.S0EditField_Local_n_right.Value;
            K = app.KEditField_Local_n_right.Value;
            mu = app.muEditField_Local_n_right.Value;
            sigma = app.sigmaEditField_Local_n_right.Value;
            kappa = app.kappaEditField_Local_n_right.Value;
            risk_premium = app.risk_premiumEditField_Local_n_right.Value;
            r = app.rEditField_Local_n_right.Value;
            T = app.TEditField_Local_n_right.Value;
            N = app.NEditField_Local_n_right.Value;
            PlotSwingLocalNR_n_right(n_time, l_min, l_max, S0, K, mu, ...
                            sigma, kappa, risk_premium, r, T, N, app.UIAxes)

            app.CompileButton_Local_n_right.Enable = 'on';
            app.StatusLabel_Local_n_right.Text = "Finish";
            drawnow;
        end

        % Value changed function: CompileButton_K_l
        function CompileButton_K_lValueChanged(app, event)
            app.StatusLabel_K_l.Text = "Running";
            app.CompileButton_K_l.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_K_l.Value;
            n_time = app.n_timeEditField_K_l.Value;
            l_min_1 = app.l_minEditField_K_l_1.Value;
            l_min_2 = app.l_minEditField_K_l_2.Value;
            l_min_3 = app.l_minEditField_K_l_3.Value;
            l_max_1 = app.l_maxEditField_K_l_1.Value;
            l_max_2 = app.l_maxEditField_K_l_2.Value;
            l_max_3 = app.l_maxEditField_K_l_3.Value;
            S0 = app.S0EditField_K_l.Value;
            K_start = app.KEditField_K_l_start.Value;
            K_step = app.KEditField_K_l_step.Value;
            K_stop = app.KEditField_K_l_stop.Value;
            mu = app.muEditField_K_l.Value;
            sigma = app.sigmaEditField_K_l.Value;
            kappa = app.kappaEditField_K_l.Value;
            risk_premium = app.risk_premiumEditField_K_l.Value;
            r = app.rEditField_K_l.Value;
            T = app.TEditField_K_l.Value;
            N = app.NEditField_K_l.Value;
            PlotSwingLocalNR_K_l(n_right, n_time, [l_min_1;l_min_2;l_min_3], [l_max_1;l_max_2;l_max_3], S0, (K_start:K_step:K_stop)', mu, ...
                            sigma, kappa, risk_premium, r, T, N, app.UIAxes);

            app.StatusLabel_K_l.Text = "Finish";
            app.CompileButton_K_l.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_K_mu
        function CompileButton_K_muValueChanged(app, event)
            app.StatusLabel_K_mu.Text = "Running";
            app.CompileButton_K_mu.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_K_mu.Value;
            n_time = app.n_timeEditField_K_mu.Value;
            l_min = app.l_minEditField_K_mu.Value;
            l_max = app.l_maxEditField_K_mu.Value;
            S0 = app.S0EditField_K_mu.Value;
            K_start = app.KEditField_K_mu_start.Value;
            K_step = app.KEditField_K_mu_step.Value;
            K_stop = app.KEditField_K_mu_stop.Value;
            mu1 = app.muEditField_K_mu_1.Value;
            mu2 = app.muEditField_K_mu_2.Value;
            mu3 = app.muEditField_K_mu_3.Value;
            sigma = app.sigmaEditField_K_mu.Value;
            kappa = app.kappaEditField_K_mu.Value;
            risk_premium = app.risk_premiumEditField_K_mu.Value;
            r = app.rEditField_K_mu.Value;
            T = app.TEditField_K_mu.Value;
            N = app.NEditField_K_mu.Value;
            PlotSwingLocalNR_K_mu(n_right, n_time, l_min, l_max, S0, (K_start:K_step:K_stop)', [mu1;mu2;mu3], ...
                            sigma, kappa, risk_premium, r, T, N, app.UIAxes);

            app.StatusLabel_K_mu.Text = "Finish";
            app.CompileButton_K_mu.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_mu_kappa
        function CompileButton_mu_kappaValueChanged(app, event)
            app.StatusLabel_mu_kappa.Text = "Running";
            app.CompileButton_mu_kappa.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_mu_kappa.Value;
            n_time = app.n_timeEditField_mu_kappa.Value;
            l_min = app.l_minEditField_mu_kappa.Value;
            l_max = app.l_maxEditField_mu_kappa.Value;
            S0 = app.S0EditField_mu_kappa.Value;
            K = app.KEditField_mu_kappa.Value;
            mu_start = app.muEditField_mu_kappa_start.Value;
            mu_step = app.muEditField_mu_kappa_step.Value;
            mu_stop = app.muEditField_mu_kappa_stop.Value;
            sigma = app.sigmaEditField_mu_kappa.Value;
            kappa_start = app.kappaEditField_mu_kappa_start.Value;
            kappa_step = app.kappaEditField_mu_kappa_step.Value;
            kappa_stop = app.kappaEditField_mu_kappa_stop.Value;
            risk_premium = app.risk_premiumEditField_mu_kappa.Value;
            r = app.rEditField_mu_kappa.Value;
            T = app.TEditField_mu_kappa.Value;
            N = app.NEditField_mu_kappa.Value;
            PlotSwingLocalNR_mu_kappa_3D(n_right, n_time, l_min, l_max, S0, K, (mu_start:mu_step:mu_stop), ...
                            sigma, (kappa_start:kappa_step:kappa_stop), risk_premium, r, T, N, app.UIAxes);

            app.StatusLabel_mu_kappa.Text = "Finish";
            app.CompileButton_mu_kappa.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_S0_kappa
        function CompileButton_S0_kappaValueChanged(app, event)
            app.StatusLabel_S0_kappa.Text = "Running";
            app.CompileButton_S0_kappa.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_S0_kappa.Value;
            n_time = app.n_timeEditField_S0_kappa.Value;
            l_min = app.l_minEditField_S0_kappa.Value;
            l_max = app.l_maxEditField_S0_kappa.Value;
            S0_start = app.S0EditField_S0_kappa_start.Value;
            S0_step = app.S0EditField_S0_kappa_step.Value;
            S0_stop = app.S0EditField_S0_kappa_stop.Value;
            K = app.KEditField_S0_kappa.Value;
            mu = app.muEditField_S0_kappa.Value;
            sigma = app.sigmaEditField_S0_kappa.Value;
            kappa_start = app.kappaEditField_S0_kappa_start.Value;
            kappa_step = app.kappaEditField_S0_kappa_step.Value;
            kappa_stop = app.kappaEditField_S0_kappa_stop.Value;
            risk_premium = app.risk_premiumEditField_S0_kappa.Value;
            r = app.rEditField_S0_kappa.Value;
            T = app.TEditField_S0_kappa.Value;
            N = app.NEditField_S0_kappa.Value;
            PlotSwingLocalNR_S0_kappa(n_right, n_time, l_min, l_max, (S0_start:S0_step:S0_stop), K, mu, ...
                            sigma, (kappa_start:kappa_step:kappa_stop), risk_premium, r, T, N, app.UIAxes);

            app.StatusLabel_S0_kappa.Text = "Finish";
            app.CompileButton_S0_kappa.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_sigma
        function CompileButton_sigmaValueChanged(app, event)
            app.StatusLabel_sigma.Text = "Running";
            app.CompileButton_sigma.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_sigma.Value;
            n_time = app.n_timeEditField_sigma.Value;
            l_min = app.l_minEditField_sigma.Value;
            l_max = app.l_maxEditField_sigma.Value;
            S0 = app.S0EditField_sigma.Value;
            K = app.KEditField_sigma.Value;
            mu = app.muEditField_sigma.Value;
            sigma_start = app.sigmaEditField_sigma_start.Value;
            sigma_step = app.sigmaEditField_sigma_step.Value;
            sigma_stop = app.sigmaEditField_sigma_stop.Value;
            kappa = app.kappaEditField_sigma.Value;
            risk_premium = app.risk_premiumEditField_sigma.Value;
            r = app.rEditField_sigma.Value;
            T = app.TEditField_sigma.Value;
            N = app.NEditField_sigma.Value;
            PlotSwingLocalNR_sigma(n_right, n_time, l_min, l_max, S0, K, mu, ...
                            (sigma_start:sigma_step:sigma_stop), kappa, risk_premium, r, T, N, app.UIAxes);

            app.StatusLabel_sigma.Text = "Finish";
            app.CompileButton_sigma.Enable = 'on';
            drawnow;
        end

        % Value changed function: CompileButton_r
        function CompileButton_rValueChanged(app, event)
            app.StatusLabel_r.Text = "Running";
            app.CompileButton_r.Enable = 'off';
            drawnow;
            cla(app.UIAxes, 'reset');
            
            n_right = app.n_rightEditField_r.Value;
            n_time = app.n_timeEditField_r.Value;
            l_min = app.l_minEditField_r.Value;
            l_max = app.l_maxEditField_r.Value;
            S0 = app.S0EditField_r.Value;
            K = app.KEditField_r.Value;
            mu = app.muEditField_r.Value;
            sigma = app.sigmaEditField_r.Value;
            kappa = app.kappaEditField_r.Value;
            expected_return_start = app.expected_returnEditField_r_start.Value;
            expected_return_step = app.expected_returnEditField_r_step.Value;
            expected_return_stop = app.expected_returnEditField_r_stop.Value;
            r_start = app.rEditField_r_start.Value;
            r_step = app.rEditField_r_step.Value;
            r_stop = app.rEditField_r_stop.Value;
            T = app.TEditField_r.Value;
            N = app.NEditField_r.Value;
            PlotSwingLocalNR_r(n_right, n_time, l_min, l_max, S0, K, mu, ...
                            sigma, kappa, (expected_return_start:expected_return_step:expected_return_stop) ...
                            , (r_start:r_step:r_stop), T, N, app.UIAxes);

            app.StatusLabel_r.Text = "Finish";
            app.CompileButton_r.Enable = 'on';
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {579, 579};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {350, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.NLocalTab = uitab(app.TabGroup);
            app.NLocalTab.Title = 'N Local';
            app.CompileButton = uibutton(app.NLocalTab, 'state');
            app.CompileButton.ValueChangedFcn = createCallbackFcn(app, @CompileButtonValueChanged, true);
            app.CompileButton.Text = 'Compile';
            app.StatusLabel = uilabel(app.NLocalTab);
            app.StatusLabel.Text = 'Status';
            app.N_stopEditField = uieditfield(app.NLocalTab, 'numeric');
            app.N_stopEditField.Limits = [0 2500];
            app.N_stopEditField.Value = 2500;
            app.N_stepEditField = uieditfield(app.NLocalTab, 'numeric');
            app.N_stepEditField.Value = 10;
            app.NEditFieldLabel = uilabel(app.NLocalTab);
            app.NEditFieldLabel.Text = 'N';
            app.N_startEditField = uieditfield(app.NLocalTab, 'numeric');
            app.N_startEditField.Value = 100;
            app.rEditFieldLabel = uilabel(app.NLocalTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.NLocalTab, 'numeric');
            app.rEditField.Value = 0.1;
            app.TEditFieldLabel = uilabel(app.NLocalTab);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.NLocalTab, 'numeric');
            app.TEditField.Value = 1;
            app.muEditFieldLabel = uilabel(app.NLocalTab);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.NLocalTab, 'numeric');
            app.muEditField.Value = 100;
            app.sigmaEditFieldLabel = uilabel(app.NLocalTab);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.NLocalTab, 'numeric');
            app.sigmaEditField.Value = 0.7;
            app.kappaEditFieldLabel = uilabel(app.NLocalTab);
            app.kappaEditFieldLabel.Text = 'kappa';
            app.kappaEditField = uieditfield(app.NLocalTab, 'numeric');
            app.kappaEditField.Value = 1;
            app.risk_premiumEditFieldLabel = uilabel(app.NLocalTab);
            app.risk_premiumEditFieldLabel.Text = 'risk_premium';
            app.risk_premiumEditField = uieditfield(app.NLocalTab, 'numeric');
            app.risk_premiumEditField.Value = 0.1;
            app.l_minEditFieldLabel = uilabel(app.NLocalTab);
            app.l_minEditFieldLabel.Text = 'l_min';
            app.l_minEditField = uieditfield(app.NLocalTab, 'numeric');
            app.l_minEditField.Value = -1;
            app.l_maxEditFieldLabel = uilabel(app.NLocalTab);
            app.l_maxEditFieldLabel.Text = 'l_max';
            app.l_maxEditField = uieditfield(app.NLocalTab, 'numeric');
            app.l_maxEditField.Value = 1;
            app.S0EditFieldLabel = uilabel(app.NLocalTab);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.NLocalTab, 'numeric');
            app.S0EditField.Value = 100;
            app.KEditFieldLabel = uilabel(app.NLocalTab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.NLocalTab, 'numeric');
            app.KEditField.Value = 100;
            app.n_rightEditFieldLabel = uilabel(app.NLocalTab);
            app.n_rightEditFieldLabel.Text = 'n_right';
            app.n_rightEditField = uieditfield(app.NLocalTab, 'numeric');
            app.n_rightEditField.Value = 2;
            app.n_timeEditFieldLabel = uilabel(app.NLocalTab);
            app.n_timeEditFieldLabel.Text = 'n_time';
            app.n_timeEditField = uieditfield(app.NLocalTab, 'numeric');
            app.n_timeEditField.Value = 5;
            app.NGlobalTab = uitab(app.TabGroup);
            app.NGlobalTab.Title = 'N Global';
            app.CompileButton_N_Global = uibutton(app.NGlobalTab, 'state');
            app.CompileButton_N_Global.ValueChangedFcn = createCallbackFcn(app, @CompileButton_N_GlobalValueChanged, true);
            app.CompileButton_N_Global.Text = 'Compile';
            app.StatusLabel_N_Global = uilabel(app.NGlobalTab);
            app.StatusLabel_N_Global.Text = 'Status';
            app.N_stopEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.N_stopEditField_N_Global.Limits = [0 2000];
            app.N_stopEditField_N_Global.Value = 2000;
            app.N_stepEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.N_stepEditField_N_Global.Value = 10;
            app.n_rightEditField_2Label = uilabel(app.NGlobalTab);
            app.n_rightEditField_2Label.Text = 'n_right';
            app.n_rightEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.n_rightEditField_N_Global.Value = 2;
            app.n_timeEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.n_timeEditField_2Label_2.Text = 'n_time';
            app.n_timeEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.n_timeEditField_N_Global.Value = 5;
            app.l_minEditField_3Label = uilabel(app.NGlobalTab);
            app.l_minEditField_3Label.Text = 'l_min';
            app.l_minEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.l_minEditField_N_Global.Value = -1;
            app.l_maxEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.l_maxEditField_2Label_2.Text = 'l_max';
            app.l_maxEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.l_maxEditField_N_Global.Value = 1;
            app.S0EditField_2Label_2 = uilabel(app.NGlobalTab);
            app.S0EditField_2Label_2.Text = 'S0';
            app.S0EditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.S0EditField_N_Global.Value = 100;
            app.KEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.KEditField_2Label_2.Text = 'K';
            app.KEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.KEditField_N_Global.Value = 100;
            app.muEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.muEditField_2Label_2.Text = 'mu';
            app.muEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.muEditField_N_Global.Value = 100;
            app.sigmaEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.sigmaEditField_2Label_2.Text = 'sigma';
            app.sigmaEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.sigmaEditField_N_Global.Value = 0.7;
            app.kappaEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.kappaEditField_2Label_2.Text = 'kappa';
            app.kappaEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.kappaEditField_N_Global.Value = 1;
            app.risk_premiumEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.risk_premiumEditField_2Label_2.Text = 'risk_premium';
            app.risk_premiumEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.risk_premiumEditField_N_Global.Value = 0.1;
            app.rEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.rEditField_2Label_2.Text = 'r';
            app.rEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.rEditField_N_Global.Value = 0.1;
            app.TEditField_2Label_2 = uilabel(app.NGlobalTab);
            app.TEditField_2Label_2.Text = 'T';
            app.TEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.TEditField_N_Global.Value = 1;
            app.NEditFieldLabel_3 = uilabel(app.NGlobalTab);
            app.NEditFieldLabel_3.Text = 'N';
            app.N_startEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.N_startEditField_N_Global.Value = 100;
            app.mEditFieldLabel = uilabel(app.NGlobalTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.mEditField_N_Global.Value = 1;
            app.g_minEditFieldLabel = uilabel(app.NGlobalTab);
            app.g_minEditFieldLabel.Text = 'g_min';
            app.g_minEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.g_minEditField_N_Global.Value = -3;
            app.g_maxEditFieldLabel = uilabel(app.NGlobalTab);
            app.g_maxEditFieldLabel.Text = 'g_max';
            app.g_maxEditField_N_Global = uieditfield(app.NGlobalTab, 'numeric');
            app.g_maxEditField_N_Global.Value = 3;
            app.n_rightLocalTab = uitab(app.TabGroup);
            app.n_rightLocalTab.Title = 'n_right Local';
            app.CompileButton_Local_n_right = uibutton(app.n_rightLocalTab, 'state');
            app.CompileButton_Local_n_right.ValueChangedFcn = createCallbackFcn(app, @CompileButton_Local_n_rightValueChanged, true);
            app.CompileButton_Local_n_right.Text = 'Compile';
            app.StatusLabel_Local_n_right = uilabel(app.n_rightLocalTab);
            app.StatusLabel_Local_n_right.Text = 'Status';
            app.n_timeEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.n_timeEditField_2Label_3.Text = 'n_time';
            app.n_timeEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.n_timeEditField_Local_n_right.Value = 5;
            app.l_minEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.l_minEditField_2Label_3.Text = 'l_min';
            app.l_minEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.l_minEditField_Local_n_right.Value = -1;
            app.l_maxEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.l_maxEditField_2Label_3.Text = 'l_max';
            app.l_maxEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.l_maxEditField_Local_n_right.Value = 1;
            app.S0EditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.S0EditField_2Label_3.Text = 'S0';
            app.S0EditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.S0EditField_Local_n_right.Value = 100;
            app.KEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.KEditField_2Label_3.Text = 'K';
            app.KEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.KEditField_Local_n_right.Value = 100;
            app.muEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.muEditField_2Label_3.Text = 'mu';
            app.muEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.muEditField_Local_n_right.Value = 100;
            app.sigmaEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.sigmaEditField_2Label_3.Text = 'sigma';
            app.sigmaEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.sigmaEditField_Local_n_right.Value = 0.7;
            app.kappaEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.kappaEditField_2Label_3.Text = 'kappa';
            app.kappaEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.kappaEditField_Local_n_right.Value = 1;
            app.rEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.rEditField_2Label_3.Text = 'r';
            app.rEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.rEditField_Local_n_right.Value = 0.1;
            app.TEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.TEditField_2Label_3.Text = 'T';
            app.TEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.TEditField_Local_n_right.Value = 1;
            app.NEditFieldLabel_4 = uilabel(app.n_rightLocalTab);
            app.NEditFieldLabel_4.Text = 'N';
            app.NEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.NEditField_Local_n_right.Value = 100;
            app.risk_premiumEditField_2Label_3 = uilabel(app.n_rightLocalTab);
            app.risk_premiumEditField_2Label_3.Text = 'risk_premium';
            app.risk_premiumEditField_Local_n_right = uieditfield(app.n_rightLocalTab, 'numeric');
            app.risk_premiumEditField_Local_n_right.Value = 0.1;
            app.n_rightGlobalTab = uitab(app.TabGroup);
            app.n_rightGlobalTab.Title = 'n_right Global';
            app.CompileButton_Global_n_right = uibutton(app.n_rightGlobalTab, 'state');
            app.CompileButton_Global_n_right.ValueChangedFcn = createCallbackFcn(app, @CompileButton_Global_n_rightValueChanged, true);
            app.CompileButton_Global_n_right.Text = 'Compile';
            app.StatusLabel_Global_n_right = uilabel(app.n_rightGlobalTab);
            app.StatusLabel_Global_n_right.Text = 'Status';
            app.n_timeEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.n_timeEditField_2Label.Text = 'n_time';
            app.n_timeEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.n_timeEditField_Global_n_right.Value = 5;
            app.l_minEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.l_minEditField_2Label.Text = 'l_min';
            app.l_minEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.l_minEditField_Global_n_right.Value = -1;
            app.l_maxEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.l_maxEditField_2Label.Text = 'l_max';
            app.l_maxEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.l_maxEditField_Global_n_right.Value = 1;
            app.S0EditField_2Label = uilabel(app.n_rightGlobalTab);
            app.S0EditField_2Label.Text = 'S0';
            app.S0EditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.S0EditField_Global_n_right.Value = 100;
            app.KEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.KEditField_2Label.Text = 'K';
            app.KEditField__Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.KEditField__Global_n_right.Value = 100;
            app.muEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.muEditField_2Label.Text = 'mu';
            app.muEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.muEditField_Global_n_right.Value = 100;
            app.sigmaEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.sigmaEditField_2Label.Text = 'sigma';
            app.sigmaEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.sigmaEditField_Global_n_right.Value = 0.7;
            app.kappaEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.kappaEditField_2Label.Text = 'kappa';
            app.kappaEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.kappaEditField_Global_n_right.Value = 1;
            app.rEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.rEditField_2Label.Text = 'r';
            app.rEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.rEditField_Global_n_right.Value = 0.1;
            app.TEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.TEditField_2Label.Text = 'T';
            app.TEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.TEditField_Global_n_right.Value = 1;
            app.NEditFieldLabel_2 = uilabel(app.n_rightGlobalTab);
            app.NEditFieldLabel_2.Text = 'N';
            app.NEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.NEditField_Global_n_right.Value = 100;
            app.l_minEditField_2Label_2 = uilabel(app.n_rightGlobalTab);
            app.l_minEditField_2Label_2.Text = 'm';
            app.mEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.mEditField_Global_n_right.Value = 1;
            app.risk_premiumEditField_2Label = uilabel(app.n_rightGlobalTab);
            app.risk_premiumEditField_2Label.Text = 'risk_premium';
            app.risk_premiumEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.risk_premiumEditField_Global_n_right.Value = 0.1;
            app.gLabel = uilabel(app.n_rightGlobalTab);
            app.gLabel.Text = 'g';
            app.g_startEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.g_startEditField_Global_n_right.Value = 1;
            app.g_stepEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.g_stepEditField_Global_n_right.Value = 1;
            app.g_stopEditField_Global_n_right = uieditfield(app.n_rightGlobalTab, 'numeric');
            app.g_stopEditField_Global_n_right.Value = 8;
            app.StricePriceandlocalconstraintTab = uitab(app.TabGroup);
            app.StricePriceandlocalconstraintTab.Title = 'Strice Price and local constraint';
            app.CompileButton_K_l = uibutton(app.StricePriceandlocalconstraintTab, 'state');
            app.CompileButton_K_l.ValueChangedFcn = createCallbackFcn(app, @CompileButton_K_lValueChanged, true);
            app.CompileButton_K_l.Text = 'Compile';
            app.StatusLabel_K_l = uilabel(app.StricePriceandlocalconstraintTab);
            app.StatusLabel_K_l.Text = 'Status';
            app.n_timeEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.n_timeEditField_2Label_4.Text = 'n_time';
            app.n_timeEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.n_timeEditField_K_l.Value = 5;
            app.l_minEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.l_minEditField_2Label_4.Text = 'l_min';
            app.l_minEditField_K_l_1 = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.l_minEditField_K_l_1.Value = -1;
            app.l_maxEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.l_maxEditField_2Label_4.Text = 'l_max';
            app.l_maxEditField_K_l_1 = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.S0EditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.S0EditField_2Label_4.Text = 'S0';
            app.S0EditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.S0EditField_K_l.Value = 100;
            app.KEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.KEditField_2Label_4.Text = 'K';
            app.KEditField_K_l_start = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.muEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.muEditField_2Label_4.Text = 'mu';
            app.muEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.muEditField_K_l.Value = 100;
            app.sigmaEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.sigmaEditField_2Label_4.Text = 'sigma';
            app.sigmaEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.sigmaEditField_K_l.Value = 0.7;
            app.kappaEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.kappaEditField_2Label_4.Text = 'kappa';
            app.kappaEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.kappaEditField_K_l.Value = 1;
            app.rEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.rEditField_2Label_4.Text = 'r';
            app.rEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.rEditField_K_l.Value = 0.1;
            app.TEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.TEditField_2Label_4.Text = 'T';
            app.TEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.TEditField_K_l.Value = 1;
            app.NEditFieldLabel_5 = uilabel(app.StricePriceandlocalconstraintTab);
            app.NEditFieldLabel_5.Text = 'N';
            app.NEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.NEditField_K_l.Value = 100;
            app.risk_premiumEditField_2Label_4 = uilabel(app.StricePriceandlocalconstraintTab);
            app.risk_premiumEditField_2Label_4.Text = 'risk_premium';
            app.risk_premiumEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.risk_premiumEditField_K_l.Value = 0.1;
            app.n_timeEditField_2Label_5 = uilabel(app.StricePriceandlocalconstraintTab);
            app.n_timeEditField_2Label_5.Text = 'n_right';
            app.n_rightEditField_K_l = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.n_rightEditField_K_l.Value = 2;
            app.l_maxEditField_K_l_2 = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.l_maxEditField_K_l_2.Value = 1;
            app.l_maxEditField_K_l_3 = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.l_maxEditField_K_l_3.Value = 1;
            app.l_minEditField_K_l_2 = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.l_minEditField_K_l_3 = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.l_minEditField_K_l_3.Value = -1;
            app.KEditField_K_l_step = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.KEditField_K_l_step.Value = 10;
            app.KEditField_K_l_stop = uieditfield(app.StricePriceandlocalconstraintTab, 'numeric');
            app.KEditField_K_l_stop.Value = 300;
            app.StrikePriceandLongrunmeanTab = uitab(app.TabGroup);
            app.StrikePriceandLongrunmeanTab.Title = 'Strike Price and Long-run mean';
            app.CompileButton_K_mu = uibutton(app.StrikePriceandLongrunmeanTab, 'state');
            app.CompileButton_K_mu.ValueChangedFcn = createCallbackFcn(app, @CompileButton_K_muValueChanged, true);
            app.CompileButton_K_mu.Text = 'Compile';
            app.KEditField_K_mu_step = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.KEditField_K_mu_step.Value = 10;
            app.n_timeEditField_2Label_6 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.n_timeEditField_2Label_6.Text = 'n_time';
            app.n_timeEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.n_timeEditField_K_mu.Value = 5;
            app.l_minEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.l_minEditField_2Label_5.Text = 'l_min';
            app.l_minEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.l_minEditField_K_mu.Value = -1;
            app.l_maxEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.l_maxEditField_2Label_5.Text = 'l_max';
            app.l_maxEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.l_maxEditField_K_mu.Value = 1;
            app.S0EditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.S0EditField_2Label_5.Text = 'S0';
            app.S0EditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.S0EditField_K_mu.Value = 100;
            app.KEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.KEditField_2Label_5.Text = 'K';
            app.KEditField_K_mu_start = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.muEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.muEditField_2Label_5.Text = 'mu';
            app.muEditField_K_mu_1 = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.muEditField_K_mu_1.Value = 50;
            app.sigmaEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.sigmaEditField_2Label_5.Text = 'sigma';
            app.sigmaEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.sigmaEditField_K_mu.Value = 0.7;
            app.kappaEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.kappaEditField_2Label_5.Text = 'kappa';
            app.kappaEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.kappaEditField_K_mu.Value = 1;
            app.rEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.rEditField_2Label_5.Text = 'r';
            app.rEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.rEditField_K_mu.Value = 0.1;
            app.TEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.TEditField_2Label_5.Text = 'T';
            app.TEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.TEditField_K_mu.Value = 1;
            app.NEditFieldLabel_6 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.NEditFieldLabel_6.Text = 'N';
            app.NEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.NEditField_K_mu.Value = 100;
            app.risk_premiumEditField_2Label_5 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.risk_premiumEditField_2Label_5.Text = 'risk_premium';
            app.risk_premiumEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.risk_premiumEditField_K_mu.Value = 0.1;
            app.n_timeEditField_2Label_7 = uilabel(app.StrikePriceandLongrunmeanTab);
            app.n_timeEditField_2Label_7.Text = 'n_right';
            app.n_rightEditField_K_mu = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.n_rightEditField_K_mu.Value = 2;
            app.KEditField_K_mu_stop = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.KEditField_K_mu_stop.Value = 300;
            app.muEditField_K_mu_2 = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.muEditField_K_mu_2.Value = 100;
            app.muEditField_K_mu_3 = uieditfield(app.StrikePriceandLongrunmeanTab, 'numeric');
            app.muEditField_K_mu_3.Value = 150;
            app.StatusLabel_K_mu = uilabel(app.StrikePriceandLongrunmeanTab);
            app.StatusLabel_K_mu.Text = 'Status';
            app.KappaandLongrunmeanTab = uitab(app.TabGroup);
            app.KappaandLongrunmeanTab.Title = 'Kappa and Long-run mean';
            app.CompileButton_mu_kappa = uibutton(app.KappaandLongrunmeanTab, 'state');
            app.CompileButton_mu_kappa.ValueChangedFcn = createCallbackFcn(app, @CompileButton_mu_kappaValueChanged, true);
            app.CompileButton_mu_kappa.Text = 'Compile';
            app.muEditField_mu_kappa_step = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.muEditField_mu_kappa_step.Value = 5;
            app.muEditField_mu_kappa_stop = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.muEditField_mu_kappa_stop.Value = 175;
            app.StatusLabel_mu_kappa = uilabel(app.KappaandLongrunmeanTab);
            app.StatusLabel_mu_kappa.Text = 'Status';
            app.n_timeEditField_2Label_8 = uilabel(app.KappaandLongrunmeanTab);
            app.n_timeEditField_2Label_8.Text = 'n_time';
            app.n_timeEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.n_timeEditField_mu_kappa.Value = 5;
            app.l_minEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.l_minEditField_2Label_6.Text = 'l_min';
            app.l_minEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.l_minEditField_mu_kappa.Value = -1;
            app.l_maxEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.l_maxEditField_2Label_6.Text = 'l_max';
            app.l_maxEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.l_maxEditField_mu_kappa.Value = 1;
            app.S0EditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.S0EditField_2Label_6.Text = 'S0';
            app.S0EditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.S0EditField_mu_kappa.Value = 100;
            app.KEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.KEditField_2Label_6.Text = 'K';
            app.KEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.KEditField_mu_kappa.Value = 100;
            app.muEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.muEditField_2Label_6.Text = 'mu';
            app.muEditField_mu_kappa_start = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.muEditField_mu_kappa_start.Value = 25;
            app.sigmaEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.sigmaEditField_2Label_6.Text = 'sigma';
            app.sigmaEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.sigmaEditField_mu_kappa.Value = 0.7;
            app.kappaEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.kappaEditField_2Label_6.Text = 'kappa';
            app.kappaEditField_mu_kappa_start = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.kappaEditField_mu_kappa_start.Value = 0.5;
            app.rEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.rEditField_2Label_6.Text = 'r';
            app.rEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.rEditField_mu_kappa.Value = 0.1;
            app.TEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.TEditField_2Label_6.Text = 'T';
            app.TEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.TEditField_mu_kappa.Value = 1;
            app.NEditFieldLabel_7 = uilabel(app.KappaandLongrunmeanTab);
            app.NEditFieldLabel_7.Text = 'N';
            app.NEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.NEditField_mu_kappa.Value = 100;
            app.risk_premiumEditField_2Label_6 = uilabel(app.KappaandLongrunmeanTab);
            app.risk_premiumEditField_2Label_6.Text = 'risk_premium';
            app.risk_premiumEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.risk_premiumEditField_mu_kappa.Value = 0.1;
            app.n_timeEditField_2Label_9 = uilabel(app.KappaandLongrunmeanTab);
            app.n_timeEditField_2Label_9.Text = 'n_right';
            app.n_rightEditField_mu_kappa = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.n_rightEditField_mu_kappa.Value = 2;
            app.kappaEditField_mu_kappa_step = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.kappaEditField_mu_kappa_step.Value = 0.1;
            app.kappaEditField_mu_kappa_stop = uieditfield(app.KappaandLongrunmeanTab, 'numeric');
            app.kappaEditField_mu_kappa_stop.Value = 5;
            app.S0andlongrunmeanTab = uitab(app.TabGroup);
            app.S0andlongrunmeanTab.Title = 'S0 and long-run mean';
            app.CompileButton_S0_kappa = uibutton(app.S0andlongrunmeanTab, 'state');
            app.CompileButton_S0_kappa.ValueChangedFcn = createCallbackFcn(app, @CompileButton_S0_kappaValueChanged, true);
            app.CompileButton_S0_kappa.Text = 'Compile';
            app.kappaEditField_S0_kappa_step = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.kappaEditField_S0_kappa_step.Value = 3;
            app.kappaEditField_S0_kappa_stop = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.kappaEditField_S0_kappa_stop.Value = 10;
            app.n_timeEditField_2Label_10 = uilabel(app.S0andlongrunmeanTab);
            app.n_timeEditField_2Label_10.Text = 'n_time';
            app.n_timeEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.n_timeEditField_S0_kappa.Value = 5;
            app.l_minEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.l_minEditField_2Label_7.Text = 'l_min';
            app.l_minEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.l_minEditField_S0_kappa.Value = -1;
            app.l_maxEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.l_maxEditField_2Label_7.Text = 'l_max';
            app.l_maxEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.l_maxEditField_S0_kappa.Value = 1;
            app.S0EditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.S0EditField_2Label_7.Text = 'S0';
            app.S0EditField_S0_kappa_start = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.S0EditField_S0_kappa_start.Value = 25;
            app.KEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.KEditField_2Label_7.Text = 'K';
            app.KEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.KEditField_S0_kappa.Value = 100;
            app.muEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.muEditField_2Label_7.Text = 'mu';
            app.muEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.muEditField_S0_kappa.Value = 100;
            app.sigmaEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.sigmaEditField_2Label_7.Text = 'sigma';
            app.sigmaEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.sigmaEditField_S0_kappa.Value = 0.7;
            app.kappaEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.kappaEditField_2Label_7.Text = 'kappa';
            app.kappaEditField_S0_kappa_start = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.kappaEditField_S0_kappa_start.Value = 1;
            app.rEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.rEditField_2Label_7.Text = 'r';
            app.rEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.rEditField_S0_kappa.Value = 0.1;
            app.TEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.TEditField_2Label_7.Text = 'T';
            app.TEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.TEditField_S0_kappa.Value = 1;
            app.NEditFieldLabel_8 = uilabel(app.S0andlongrunmeanTab);
            app.NEditFieldLabel_8.Text = 'N';
            app.NEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.NEditField_S0_kappa.Value = 1000;
            app.risk_premiumEditField_2Label_7 = uilabel(app.S0andlongrunmeanTab);
            app.risk_premiumEditField_2Label_7.Text = 'risk_premium';
            app.risk_premiumEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.risk_premiumEditField_S0_kappa.Value = 0.1;
            app.n_timeEditField_2Label_11 = uilabel(app.S0andlongrunmeanTab);
            app.n_timeEditField_2Label_11.Text = 'n_right';
            app.n_rightEditField_S0_kappa = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.n_rightEditField_S0_kappa.Value = 2;
            app.S0EditField_S0_kappa_step = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.S0EditField_S0_kappa_step.Value = 10;
            app.S0EditField_S0_kappa_stop = uieditfield(app.S0andlongrunmeanTab, 'numeric');
            app.S0EditField_S0_kappa_stop.Value = 175;
            app.StatusLabel_S0_kappa = uilabel(app.S0andlongrunmeanTab);
            app.StatusLabel_S0_kappa.Text = 'Status';
            app.sigmaTab = uitab(app.TabGroup);
            app.sigmaTab.Title = 'sigma';
            app.CompileButton_sigma = uibutton(app.sigmaTab, 'state');
            app.CompileButton_sigma.ValueChangedFcn = createCallbackFcn(app, @CompileButton_sigmaValueChanged, true);
            app.CompileButton_sigma.Text = 'Compile';
            app.StatusLabel_sigma = uilabel(app.sigmaTab);
            app.StatusLabel_sigma.Text = 'Status';
            app.n_timeEditField_2Label_12 = uilabel(app.sigmaTab);
            app.n_timeEditField_2Label_12.Text = 'n_time';
            app.n_timeEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.n_timeEditField_sigma.Value = 5;
            app.l_minEditField_2Label_8 = uilabel(app.sigmaTab);
            app.l_minEditField_2Label_8.Text = 'l_min';
            app.l_minEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.l_minEditField_sigma.Value = -1;
            app.l_maxEditField_2Label_8 = uilabel(app.sigmaTab);
            app.l_maxEditField_2Label_8.Text = 'l_max';
            app.l_maxEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.l_maxEditField_sigma.Value = 1;
            app.S0EditField_2Label_8 = uilabel(app.sigmaTab);
            app.S0EditField_2Label_8.Text = 'S0';
            app.S0EditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.S0EditField_sigma.Value = 100;
            app.KEditField_2Label_8 = uilabel(app.sigmaTab);
            app.KEditField_2Label_8.Text = 'K';
            app.KEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.KEditField_sigma.Value = 100;
            app.muEditField_2Label_8 = uilabel(app.sigmaTab);
            app.muEditField_2Label_8.Text = 'mu';
            app.muEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.muEditField_sigma.Value = 100;
            app.sigmaEditField_2Label_8 = uilabel(app.sigmaTab);
            app.sigmaEditField_2Label_8.Text = 'sigma';
            app.sigmaEditField_sigma_start = uieditfield(app.sigmaTab, 'numeric');
            app.sigmaEditField_sigma_start.Value = 0.05;
            app.kappaEditField_2Label_8 = uilabel(app.sigmaTab);
            app.kappaEditField_2Label_8.Text = 'kappa';
            app.kappaEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.kappaEditField_sigma.Value = 1;
            app.rEditField_2Label_8 = uilabel(app.sigmaTab);
            app.rEditField_2Label_8.Text = 'r';
            app.rEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.rEditField_sigma.Value = 0.1;
            app.TEditField_2Label_8 = uilabel(app.sigmaTab);
            app.TEditField_2Label_8.Text = 'T';
            app.TEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.TEditField_sigma.Value = 1;
            app.NEditFieldLabel_9 = uilabel(app.sigmaTab);
            app.NEditFieldLabel_9.Text = 'N';
            app.NEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.NEditField_sigma.Value = 1000;
            app.risk_premiumEditField_2Label_8 = uilabel(app.sigmaTab);
            app.risk_premiumEditField_2Label_8.Text = 'risk_premium';
            app.risk_premiumEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.risk_premiumEditField_sigma.Value = 0.1;
            app.n_timeEditField_2Label_13 = uilabel(app.sigmaTab);
            app.n_timeEditField_2Label_13.Text = 'n_right';
            app.n_rightEditField_sigma = uieditfield(app.sigmaTab, 'numeric');
            app.n_rightEditField_sigma.Value = 2;
            app.sigmaEditField_sigma_step = uieditfield(app.sigmaTab, 'numeric');
            app.sigmaEditField_sigma_step.Value = 0.01;
            app.sigmaEditField_sigma_stop = uieditfield(app.sigmaTab, 'numeric');
            app.sigmaEditField_sigma_stop.Value = 0.7;
            app.riskfreeTab = uitab(app.TabGroup);
            app.riskfreeTab.Title = 'risk-free';
            app.CompileButton_r = uibutton(app.riskfreeTab, 'state');
            app.CompileButton_r.ValueChangedFcn = createCallbackFcn(app, @CompileButton_rValueChanged, true);
            app.CompileButton_r.Text = 'Compile';
            app.StatusLabel_r = uilabel(app.riskfreeTab);
            app.StatusLabel_r.Text = 'Status';
            app.n_timeEditField_2Label_14 = uilabel(app.riskfreeTab);
            app.n_timeEditField_2Label_14.Text = 'n_time';
            app.n_timeEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.n_timeEditField_r.Value = 5;
            app.l_minEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.l_minEditField_2Label_9.Text = 'l_min';
            app.l_minEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.l_minEditField_r.Value = -1;
            app.l_maxEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.l_maxEditField_2Label_9.Text = 'l_max';
            app.l_maxEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.l_maxEditField_r.Value = 1;
            app.S0EditField_2Label_9 = uilabel(app.riskfreeTab);
            app.S0EditField_2Label_9.Text = 'S0';
            app.S0EditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.S0EditField_r.Value = 100;
            app.KEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.KEditField_2Label_9.Text = 'K';
            app.KEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.KEditField_r.Value = 100;
            app.muEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.muEditField_2Label_9.Text = 'mu';
            app.muEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.muEditField_r.Value = 100;
            app.sigmaEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.sigmaEditField_2Label_9.Text = 'sigma';
            app.sigmaEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.sigmaEditField_r.Value = 0.7;
            app.kappaEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.kappaEditField_2Label_9.Text = 'kappa';
            app.kappaEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.kappaEditField_r.Value = 1;
            app.rEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.rEditField_2Label_9.Text = 'r';
            app.rEditField_r_start = uieditfield(app.riskfreeTab, 'numeric');
            app.rEditField_r_start.Value = 0.05;
            app.TEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.TEditField_2Label_9.Text = 'T';
            app.TEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.TEditField_r.Value = 1;
            app.NEditFieldLabel_10 = uilabel(app.riskfreeTab);
            app.NEditFieldLabel_10.Text = 'N';
            app.NEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.NEditField_r.Value = 1000;
            app.risk_premiumEditField_2Label_9 = uilabel(app.riskfreeTab);
            app.risk_premiumEditField_2Label_9.Text = 'expected_return';
            app.expected_returnEditField_r_start = uieditfield(app.riskfreeTab, 'numeric');
            app.n_timeEditField_2Label_15 = uilabel(app.riskfreeTab);
            app.n_timeEditField_2Label_15.Text = 'n_right';
            app.n_rightEditField_r = uieditfield(app.riskfreeTab, 'numeric');
            app.n_rightEditField_r.Value = 2;
            app.expected_returnEditField_r_step = uieditfield(app.riskfreeTab, 'numeric');
            app.expected_returnEditField_r_step.Value = 0.05;
            app.expected_returnEditField_r_stop = uieditfield(app.riskfreeTab, 'numeric');
            app.expected_returnEditField_r_stop.Value = 0.15;
            app.rEditField_r_step = uieditfield(app.riskfreeTab, 'numeric');
            app.rEditField_r_step.Value = 0.05;
            app.rEditField_r_stop = uieditfield(app.riskfreeTab, 'numeric');
            app.rEditField_r_stop.Value = 0.95;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end